classdef tesinaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLRSignalAnalysisUIFigure       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        STARTANALYZINGButton            matlab.ui.control.Button
        CANCELButton_5                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        HelpLabel                       matlab.ui.control.Label
        ParametersTabLabel              matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        CreatedSignalsTabLabel          matlab.ui.control.Label
        HTML_2                          matlab.ui.control.HTML
        FilterParametersTabLabel        matlab.ui.control.Label
        HTML_3                          matlab.ui.control.HTML
        FilteredSignalsTabLabel         matlab.ui.control.Label
        HTML_4                          matlab.ui.control.HTML
        HTML2                           matlab.ui.control.HTML
        HTML2_2                         matlab.ui.control.HTML
        HTML2_3                         matlab.ui.control.HTML
        BACKButton                      matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.Tab
        DetermineSignalParametersLabel  matlab.ui.control.Label
        sinxCheckBox                    matlab.ui.control.CheckBox
        sinxCheckBox_2                  matlab.ui.control.CheckBox
        sinxCheckBox_3                  matlab.ui.control.CheckBox
        sinxCheckBox_4                  matlab.ui.control.CheckBox
        sinxCheckBox_5                  matlab.ui.control.CheckBox
        CANCELButton                    matlab.ui.control.Button
        CREATEButton                    matlab.ui.control.Button
        SlopemmyrEditFieldLabel         matlab.ui.control.Label
        SlopemmyrEditField              matlab.ui.control.NumericEditField
        AmplitudeLabel                  matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditField_7Label       matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.NumericEditField
        AmplitudeEditField_8Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        AmplitudeEditField_9Label       matlab.ui.control.Label
        AmplitudeEditField_4            matlab.ui.control.NumericEditField
        AmplitudeEditField_10Label      matlab.ui.control.Label
        AmplitudeEditField_5            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditField_2Label       matlab.ui.control.Label
        FrequencyEditField_2            matlab.ui.control.NumericEditField
        FrequencyEditField_3Label       matlab.ui.control.Label
        FrequencyEditField_3            matlab.ui.control.NumericEditField
        FrequencyEditField_4Label       matlab.ui.control.Label
        FrequencyEditField_4            matlab.ui.control.NumericEditField
        FrequencyEditField_5Label       matlab.ui.control.Label
        FrequencyEditField_5            matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseEditField_2Label           matlab.ui.control.Label
        PhaseEditField_2                matlab.ui.control.NumericEditField
        PhaseEditField_3Label           matlab.ui.control.Label
        PhaseEditField_3                matlab.ui.control.NumericEditField
        PhaseEditField_4Label           matlab.ui.control.Label
        PhaseEditField_4                matlab.ui.control.NumericEditField
        PhaseEditField_5Label           matlab.ui.control.Label
        PhaseEditField_5                matlab.ui.control.NumericEditField
        Button_2                        matlab.ui.control.Button
        CreatedSignalsTab               matlab.ui.container.Tab
        CreatedSignalsLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        CANCELButton_2                  matlab.ui.control.Button
        NEXTButton                      matlab.ui.control.Button
        GOBACKButton                    matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        FilterParamsTab                 matlab.ui.container.Tab
        FilterSignalParametersLabel     matlab.ui.control.Label
        TimescaleYearsEditFieldLabel    matlab.ui.control.Label
        TimescaleYearsEditField         matlab.ui.control.NumericEditField
        CANCELButton_3                  matlab.ui.control.Button
        FILTERButton                    matlab.ui.control.Button
        GOBACKButton_2                  matlab.ui.control.Button
        SelectaFilterLabel              matlab.ui.control.Label
        BoxcarFilterCheckBox            matlab.ui.control.CheckBox
        SincFilterCheckBox              matlab.ui.control.CheckBox
        SelectaSeriesLabel              matlab.ui.control.Label
        TimeSeriesCheckBox              matlab.ui.control.CheckBox
        PowerSpectrumCheckBox           matlab.ui.control.CheckBox
        Button_4                        matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        FilteredSignalsTab              matlab.ui.container.Tab
        FilteredSignalsLabel            matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        CANCELButton_4                  matlab.ui.control.Button
        NEXTButton_2                    matlab.ui.control.Button
        GOBACKButton_3                  matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        SignalAnalysisTab               matlab.ui.container.Tab
        AnalyzingtheFilteredSignalsLabel  matlab.ui.control.Label
        HTML4                           matlab.ui.control.HTML
        FINISHButton                    matlab.ui.control.Button
        GOBACKButton_4                  matlab.ui.control.Button
    end

  properties (Access = public)
      % variables that need to be used throughout different methods in the
      % application
      totalSignal
      time
      powerSignal
      selectedFilter
      selectedSeries
  end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sinxCheckBox
        function sinxCheckBoxValueChanged(app, event)
            % if the sinx checkbox is selected all the input fields turn
            % enabled; if it is unchecked they turn unenabled
            value = app.sinxCheckBox.Value;
            app.AmplitudeEditField.Enable = true;
            app.AmplitudeEditField.Editable = true;
            app.FrequencyEditField.Enable = true;
            app.PhaseEditField.Enable = true;
            if(value == false)
                app.AmplitudeEditField.Enable = false;
                app.FrequencyEditField.Enable = false;
                app.PhaseEditField.Enable = false;               
                app.AmplitudeEditField.Value = 0;
                app.FrequencyEditField.Value = 0;
                app.PhaseEditField.Value = 0;
            end

        end

        % Value changed function: sinxCheckBox_2
        function sinxCheckBox_2ValueChanged(app, event)
            % if the sinx checkbox is selected all the input fields turn
            % enabled; if it is unchecked they turn unenabled
            value = app.sinxCheckBox_2.Value;
            app.AmplitudeEditField_2.Enable = true;
            app.FrequencyEditField_2.Enable = true;
            app.PhaseEditField_2.Enable = true;
            if(value == false)
                app.AmplitudeEditField_2.Enable = false;
                app.FrequencyEditField_2.Enable = false;
                app.PhaseEditField_2.Enable = false;
                app.AmplitudeEditField_2.Value = 0;
                app.FrequencyEditField_2.Value = 0;
                app.PhaseEditField_2.Value = 0;
            end
        end

        % Value changed function: sinxCheckBox_3
        function sinxCheckBox_3ValueChanged(app, event)
            % if the sinx checkbox is selected all the input fields turn
            % enabled; if it is unchecked they turn unenabled
            value = app.sinxCheckBox_3.Value;
            app.AmplitudeEditField_3.Enable = true;
            app.FrequencyEditField_3.Enable = true;
            app.PhaseEditField_3.Enable = true;          
            if(value == false)
                app.AmplitudeEditField_3.Enable = false;              
                app.FrequencyEditField_3.Enable = false;              
                app.PhaseEditField_3.Enable = false;
                app.AmplitudeEditField_3.Value = 0;
                app.FrequencyEditField_3.Value = 0;
                app.PhaseEditField_3.Value = 0;
            end
        end

        % Value changed function: sinxCheckBox_4
        function sinxCheckBox_4ValueChanged(app, event)
            % if the sinx checkbox is selected all the input fields turn
            % enabled; if it is unchecked they turn unenabled
            value = app.sinxCheckBox_4.Value;
            app.AmplitudeEditField_4.Enable = true;
            app.FrequencyEditField_4.Enable = true;
            app.PhaseEditField_4.Enable = true;
            if(value == false)
                app.AmplitudeEditField_4.Enable = false;
                app.FrequencyEditField_4.Enable = false;
                app.PhaseEditField_4.Enable = false;
                app.AmplitudeEditField_4.Value = 0;
                app.FrequencyEditField_4.Value = 0;
                app.PhaseEditField_4.Value = 0;
            end
        end

        % Value changed function: sinxCheckBox_5
        function sinxCheckBox_5ValueChanged(app, event)
            % if the sinx checkbox is selected all the input fields turn
            % enabled; if it is unchecked they turn unenabled
            value = app.sinxCheckBox_5.Value;
            app.AmplitudeEditField_5.Enable = true;
            app.FrequencyEditField_5.Enable = true;
            app.PhaseEditField_5.Enable = true;
            if(value == false)
                app.AmplitudeEditField_5.Enable = false;
                app.FrequencyEditField_5.Enable = false;
                app.PhaseEditField_5.Enable = false;
                app.AmplitudeEditField_5.Value = 0;
                app.FrequencyEditField_5.Value = 0;
                app.PhaseEditField_5.Value = 0;
            end
        end

        % Button pushed function: CANCELButton, CANCELButton_2, 
        % CANCELButton_3, CANCELButton_4, CANCELButton_5
        function CANCELButtonPushed(app, event)
             delete(app.SLRSignalAnalysisUIFigure);
        end

        % Button pushed function: CREATEButton
        function CREATEButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CreatedSignalsTab;
            
            ampl_01 = app.AmplitudeEditField.Value;
            ampl_02 = app.AmplitudeEditField_2.Value;
            ampl_03 = app.AmplitudeEditField_3.Value;
            ampl_04 = app.AmplitudeEditField_4.Value;
            ampl_05 = app.AmplitudeEditField_5.Value;
            
            totalAmp = ampl_01 + ampl_02 + ampl_03 + ampl_04 + ampl_05;
            
            freq_01 = app.FrequencyEditField.Value;
            freq_02 = app.FrequencyEditField_2.Value;
            freq_03 = app.FrequencyEditField_3.Value;
            freq_04 = app.FrequencyEditField_4.Value;
            freq_05 = app.FrequencyEditField_5.Value;
            
            totalFreq = freq_01 + freq_02 + freq_03 + freq_04 + freq_05;
            
            phase_01 = app.PhaseEditField.Value;
            phase_02 = app.PhaseEditField_2.Value;
            phase_03 = app.PhaseEditField_3.Value;
            phase_04 = app.PhaseEditField_4.Value;
            phase_05 = app.PhaseEditField_5.Value;
            
            totalPhase = phase_01 + phase_02 + phase_03 + phase_04 + phase_05;
            
            app.time = 0:2*pi/16:totalFreq;
            
            app.totalSignal = 0;
            
            %% function -- A * sin(f * t + ph)
            
            signal1 = ampl_01 * sin(freq_01 * app.time + phase_01);
            app.totalSignal = app.totalSignal + signal1;
            
            signal2 = ampl_02 * sin(freq_02 * app.time + phase_02);
            app.totalSignal = app.totalSignal + signal2;
            
            signal3 = ampl_03 * sin(freq_03 * app.time + phase_03);
            app.totalSignal = app.totalSignal + signal3;
            
            signal4 = ampl_04 * sin(freq_04 * app.time + phase_04);
            app.totalSignal = app.totalSignal + signal4;
            
            signal5 = ampl_05 * sin(freq_05 * app.time + phase_05);
            app.totalSignal = app.totalSignal + signal5;
           
            app.totalSignal = app.totalSignal + randn(1, length(app.totalSignal));
            
            slope = app.SlopemmyrEditField.Value;
            trend = slope * app.time;
            
            % 1 / totalFreq --> helps create the power spectrum
            
            x = totalAmp * sin((1 /totalFreq) * app.time + totalPhase);
            app.powerSignal = periodogram(x);
            
            %% time series plot
            
            plot(app.UIAxes, app.time, app.totalSignal, 'k');
            hold(app.UIAxes, 'on');
            
            % power spectrum plot 
            
            loglog(app.UIAxes2, app.powerSignal, 'k');
            hold(app.UIAxes2, 'on');


        end

        % Button pushed function: GOBACKButton
        function GOBACKButtonPushed(app, event)
        % If the user clicks on the Go Back Button - all the parameters value turn to 0   
            app.TabGroup.SelectedTab = app.ParametersTab;
            
            app.AmplitudeEditField.Value = 0;
            app.AmplitudeEditField_2.Value = 0;
            app.AmplitudeEditField_3.Value = 0;
            app.AmplitudeEditField_4.Value = 0;
            app.AmplitudeEditField_5.Value = 0;
            
            app.FrequencyEditField.Value = 0;
            app.FrequencyEditField_2.Value = 0;
            app.FrequencyEditField_3.Value = 0;
            app.FrequencyEditField_4.Value = 0;
            app.FrequencyEditField_5.Value = 0;
            
            app.PhaseEditField.Value = 0;
            app.PhaseEditField_2.Value = 0;
            app.PhaseEditField_3.Value = 0;
            app.PhaseEditField_4.Value = 0;
            app.PhaseEditField_5.Value = 0;
            
            hold(app.UIAxes, 'off');
            hold(app.UIAxes2, 'off');

        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FilterParamsTab;
        end

        % Button pushed function: FILTERButton
        function FILTERButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FilteredSignalsTab;
            
            % variables used in the distinct filters
            filterYrs = app.TimescaleYearsEditField.Value;
            
            if filterYrs == 0
                filterYrs = 1;
            end
            
            points = 2 * filterYrs * 365 + 1;
            if points / 2 == fix(points/2)
                points = points + 1;
            end
            
            filterYrsInDays = filterYrs * 365; 
            frequency1 = 1 / (filterYrsInDays); 

            windowSize = filterYrs;
            b = (1 / windowSize) * ones(1, windowSize);
            a = 1;
            
            disp(frequency1 * 1000 * filterYrs);
            disp(length(app.totalSignal));
            
            %% TODO - terminar la logica [linear regression (total signal), poner los limites de los filtros]
            
            if (strcmp(app.selectedSeries, 'Time Series') && strcmp(app.selectedFilter, 'Boxcar Filter')) || (strcmp(app.selectedSeries, '') && strcmp(app.selectedFilter, ''))
                %% Time Series (w/out filters)
                plot(app.UIAxes_2, app.totalSignal, 'LineWidth', 0.8,'Color','k');
                hold(app.UIAxes_2,'on');
                
                %% Time Series (with filters)
                filterTimeSeries = filter(b, a, app.totalSignal);
                
                modelF = fitlm(app.totalSignal(filterYrsInDays & size(app.totalSignal)), filterTimeSeries(filterYrsInDays & size(filterTimeSeries)), 'linear');
                SLRregression = modelF.Coefficients.Estimate(2)*points/100;
                
                plot(app.UIAxes_2, filterTimeSeries, 'LineWidth', 1,'Color','b');
                plot(app.UIAxes_2, modelF.Coefficients.Estimate(2) * filterTimeSeries + modelF.Coefficients.Estimate(1), 'y');
                loglog(app.UIAxes_2, [(filterYrsInDays/4) (filterYrsInDays/4)], [-filterYrs filterYrs], 'LineWidth', 2,'Color','r');
                plot(app.UIAxes_2, [((points/2) - (filterYrsInDays/4)) ((points/2) - (filterYrsInDays/4))], [-filterYrs filterYrs], 'LineWidth', 2,'Color','r');
                txt = ['SLR estimate: ', num2str(abs(SLRregression)), 'mm/yr'];
                text(app.UIAxes_2, 100 , (filterYrs + 3), txt);
                hold(app.UIAxes_2, 'off');
                legend(app.UIAxes_2, 'Total Signal', 'Boxcar Filter Signal', 'Linear Regression', 'Filter and Effect', 'Location',"southwest");
                
                %% Power Spectrum
                loglog(app.UIAxes2_2, app.powerSignal,'LineWidth', 1,'Color', 'k');
                hold(app.UIAxes2_2, 'on');
                legend(app.UIAxes2_2, 'Power Spectrum', 'Location',"southwest");
                
            end 
            
            % IMPROVEMENT NEEDED: linear regression
            if strcmp(app.selectedSeries, 'Time Series') && strcmp(app.selectedFilter, 'Sinc Filter')
                %% Time Series (w/out filters)
                plot(app.UIAxes_2, app.totalSignal, 'LineWidth', 0.8, 'Color', 'k');
                hold(app.UIAxes_2,'on');
                
                %% Time Series (with filters)
                sincTotalSignal = sinc(app.totalSignal);
                filterSincSignal = filter(b, a, sincTotalSignal);
                
                modelF = fitlm(app.totalSignal(filterYrsInDays & size(app.totalSignal)), filterSincSignal(filterYrsInDays & size(filterSincSignal)), 'linear');
                SLRregression = modelF.Coefficients.Estimate(2)*1000;
                
                plot(app.UIAxes_2, filterSincSignal, 'LineWidth', 1, 'Color', 'b');
                plot(app.UIAxes_2,modelF.Coefficients.Estimate(2) * filterSincSignal + modelF.Coefficients.Estimate(1), 'LineWidth', 2,'Color','y');
                
                % plotting of filter limits
                loglog(app.UIAxes_2, [(filterYrsInDays/4) (filterYrsInDays/4)], [-filterYrs filterYrs], 'LineWidth', 2,'Color','r');
                plot(app.UIAxes_2, [((points/2) - (filterYrsInDays/4)) ((points/2) - (filterYrsInDays/4))], [-filterYrs filterYrs], 'LineWidth', 2,'Color','r');
                txt = ['SLR estimate: ', num2str(abs(SLRregression)), 'mm/yr'];
                text(app.UIAxes_2, 100 , (filterYrs + 3.5), txt);
                hold(app.UIAxes_2, "off");
                
                legend(app.UIAxes_2, 'Total Signal', 'Sinc Filter Signal', 'Linear Regression', 'Filters and Effect', 'Location',"southwest");
                
                %% Power Spectrum
                loglog(app.UIAxes2_2, app.powerSignal,'LineWidth', 0.8,'Color', 'k');
                legend(app.UIAxes2_2, 'Power Spectrum', 'Location',"southwest");
                hold(app.UIAxes2_2, 'on');
            end
            
            if strcmp(app.selectedSeries, 'Power Spectrum') && strcmp(app.selectedFilter, 'Boxcar Filter')
                %% Time Series
                plot(app.UIAxes_2, app.totalSignal, 'k');
                legend(app.UIAxes_2, 'Total Signal', 'Location',"southwest");
                hold(app.UIAxes_2,'on');
                
                %% Power Spectrum (w/out filters)
                loglog(app.UIAxes2_2, app.powerSignal,'LineWidth', 0.8,'Color', 'k');
                hold(app.UIAxes2_2, 'on');
                
                %% Power Spectrum (with filters)
                filterBoxcarSpectrum = filter(b, a, app.powerSignal);
                loglog(app.UIAxes2_2, filterBoxcarSpectrum,'LineWidth', 1,'Color','r');
                loglog(app.UIAxes2_2, [filterYrs filterYrs], [10e-5 10e3], 'LineWidth', 1,'Color', 'g');
                
                hold(app.UIAxes2_2, 'off');
                legend(app.UIAxes2_2, 'Power Spectrum', 'Boxcar Filter Spectrum', 'Cutoff - Filter Years','Location',"northeast");
            end
            
            % fix - sinc filter ; DONE: cutoff filter 
            if strcmp(app.selectedSeries, 'Power Spectrum') && strcmp(app.selectedFilter, 'Sinc Filter')
                %% Time Series
                plot(app.UIAxes_2, app.totalSignal, 'k');
                legend(app.UIAxes_2, 'Total Signal', 'Location',"southwest");
                hold(app.UIAxes_2,'on');
                
               %% Power Spectrum (w/out filters)
                loglog(app.UIAxes2_2, app.powerSignal,'LineWidth', 0.8,'Color', 'k');
                hold(app.UIAxes2_2, 'on');
                
                %% Power Spectrum (with filters)
                fftSpectrum = lowpass(app.powerSignal,frequency1);
                
                filterSincSpectrum = filter(b, a, fftSpectrum);
                loglog(app.UIAxes2_2, fftSpectrum,'LineWidth', 1,'Color','r');
                loglog(app.UIAxes2_2, [filterYrs filterYrs], [10e-3 10e3], 'LineWidth', 1,'Color', 'g');
          
                hold(app.UIAxes2_2, 'off');
                legend(app.UIAxes2_2, 'Power Spectrum', 'Sinc Filter Spectrum', 'Cutoff - Filter Years','Location',"northeast");
            end


        end

        % Button pushed function: GOBACKButton_2
        function GOBACKButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.CreatedSignalsTab;
        end

        % Button pushed function: GOBACKButton_3
        function GOBACKButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.FilterParamsTab;
        end

        % Button pushed function: STARTANALYZINGButton
        function STARTANALYZINGButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParametersTab;
        end

        % Value changed function: BoxcarFilterCheckBox
        function BoxcarFilterCheckBoxValueChanged(app, event)
            value = app.BoxcarFilterCheckBox.Value;
            
            if (value == true)
                app.selectedFilter = 'Boxcar Filter';
                app.SincFilterCheckBox.Enable = false;
            else
                app.selectedFilter = '';
                app.SincFilterCheckBox.Enable = true;
            end
        end

        % Value changed function: SincFilterCheckBox
        function SincFilterCheckBoxValueChanged(app, event)
            value = app.SincFilterCheckBox.Value;
            
            if (value == true)
                app.selectedFilter = 'Sinc Filter';
                app.BoxcarFilterCheckBox.Enable = false;
            else
                app.selectedFilter = '';
                app.BoxcarFilterCheckBox.Enable = true;
            end
        end

        % Value changed function: TimeSeriesCheckBox
        function TimeSeriesCheckBoxValueChanged(app, event)
            value = app.TimeSeriesCheckBox.Value;
            
            if (value == true)
                app.selectedSeries = 'Time Series';
                app.PowerSpectrumCheckBox.Enable = false;
            else
                app.selectedSeries = '';
                app.PowerSpectrumCheckBox.Enable = true;
            end
        end

        % Value changed function: PowerSpectrumCheckBox
        function PowerSpectrumCheckBoxValueChanged(app, event)
            value = app.PowerSpectrumCheckBox.Value;
            
            if (value == true)
                app.selectedSeries = 'Power Spectrum';
                app.TimeSeriesCheckBox.Enable = false;
            else
                app.selectedSeries = '';
                app.TimeSeriesCheckBox.Enable = true;
            end
        end

        % Button pushed function: Button, Button_2
        function ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HelpTab;
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParametersTab;
        end

        % Button pushed function: NEXTButton_2
        function NEXTButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.SignalAnalysisTab;
        end

        % Button pushed function: GOBACKButton_4
        function GOBACKButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.FilteredSignalsTab;
        end

        % Button pushed function: FINISHButton
        function FINISHButtonPushed(app, event)
            delete(app.SLRSignalAnalysisUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SLRSignalAnalysisUIFigure = uifigure('Visible', 'off');
            app.SLRSignalAnalysisUIFigure.Name = 'SLR Signal Analysis';
            app.TabGroup = uitabgroup(app.SLRSignalAnalysisUIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.STARTANALYZINGButton = uibutton(app.StartTab, 'push');
            app.STARTANALYZINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTANALYZINGButtonPushed, true);
            app.STARTANALYZINGButton.Text = 'START ANALYZING';
            app.CANCELButton_5 = uibutton(app.StartTab, 'push');
            app.CANCELButton_5.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton_5.Text = 'CANCEL';
            app.Image = uiimage(app.StartTab);
            app.Image.ImageSource = 'innovations.png';
            app.Button = uibutton(app.StartTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'help.png';
            app.Button.Tooltip = {'Help'};
            app.Button.Text = '';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'HelpTab';
            app.HelpLabel = uilabel(app.HelpTab);
            app.HelpLabel.Text = 'Help';
            app.ParametersTabLabel = uilabel(app.HelpTab);
            app.ParametersTabLabel.Text = '  Parameters Tab:  ';
            app.HTML = uihtml(app.HelpTab);
            app.HTML.HTMLSource = '<p style="font-family: Helvetica; font-size: 15px">Select the amount of sin functions desired to create the signal that will be processed with its distinct parameters:<ul style="font-family: Helvetica; font-size: 15px"> <li> Amplitude (meters) </li><li> Frequency (cycles per day)</li> <li> Phase (meters) </li> </ul></p>';
            app.CreatedSignalsTabLabel = uilabel(app.HelpTab);
            app.CreatedSignalsTabLabel.Text = '  Created Signals Tab:  ';
            app.HTML_2 = uihtml(app.HelpTab);
            app.HTML_2.HTMLSource = '<p style="font-family: Helvetica; font-size: 15px">Demonstrates the generated signals based on the parameters entered on the <span style="font-weight: 600">Parameters Tab</span></p>';
            app.FilterParametersTabLabel = uilabel(app.HelpTab);
            app.FilterParametersTabLabel.Text = '  Filter Parameters Tab:  ';
            app.HTML_3 = uihtml(app.HelpTab);
            app.HTML_3.HTMLSource = '<p style="font-family: Helvetica; font-size: 15px">Enter the amount of years that will be used to filter the generated signals in <span style="font-weight: 600"> Created Signals</span> and select which filter you would like to apply to the signals.</p>';
            app.FilteredSignalsTabLabel = uilabel(app.HelpTab);
            app.FilteredSignalsTabLabel.Text = '  Filtered Signals Tab:  ';
            app.HTML_4 = uihtml(app.HelpTab);
            app.HTML_4.HTMLSource = '<p style="font-family: Helvetica; font-size: 15px">Demonstrates the filtered signals based on the timescale entered and filter selected on the <span style="font-weight: 600">Filter Parameters Tab</span></p>';
            app.HTML2 = uihtml(app.HelpTab);
            app.HTML2.HTMLSource = '<hr/>';
            app.HTML2_2 = uihtml(app.HelpTab);
            app.HTML2_2.HTMLSource = '<hr/>';
            app.HTML2_3 = uihtml(app.HelpTab);
            app.HTML2_3.HTMLSource = '<hr/>';
            app.BACKButton = uibutton(app.HelpTab, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.DetermineSignalParametersLabel = uilabel(app.ParametersTab);
            app.DetermineSignalParametersLabel.Text = 'Determine Signal Parameters';
            app.sinxCheckBox = uicheckbox(app.ParametersTab);
            app.sinxCheckBox.ValueChangedFcn = createCallbackFcn(app, @sinxCheckBoxValueChanged, true);
            app.sinxCheckBox.Text = '   sin x';
            app.sinxCheckBox_2 = uicheckbox(app.ParametersTab);
            app.sinxCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @sinxCheckBox_2ValueChanged, true);
            app.sinxCheckBox_2.Text = '   sin x';
            app.sinxCheckBox_3 = uicheckbox(app.ParametersTab);
            app.sinxCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @sinxCheckBox_3ValueChanged, true);
            app.sinxCheckBox_3.Text = '   sin x';
            app.sinxCheckBox_4 = uicheckbox(app.ParametersTab);
            app.sinxCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @sinxCheckBox_4ValueChanged, true);
            app.sinxCheckBox_4.Text = '   sin x';
            app.sinxCheckBox_5 = uicheckbox(app.ParametersTab);
            app.sinxCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @sinxCheckBox_5ValueChanged, true);
            app.sinxCheckBox_5.Text = '   sin x';
            app.CANCELButton = uibutton(app.ParametersTab, 'push');
            app.CANCELButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton.Text = 'CANCEL';
            app.CREATEButton = uibutton(app.ParametersTab, 'push');
            app.CREATEButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEButtonPushed, true);
            app.CREATEButton.Text = 'CREATE';
            app.SlopemmyrEditFieldLabel = uilabel(app.ParametersTab);
            app.SlopemmyrEditFieldLabel.Text = 'Slope (mm/yr)';
            app.SlopemmyrEditField = uieditfield(app.ParametersTab, 'numeric');
            app.AmplitudeLabel = uilabel(app.ParametersTab);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ParametersTab, 'numeric');
            app.AmplitudeEditField.Enable = 'off';
            app.AmplitudeEditField_7Label = uilabel(app.ParametersTab);
            app.AmplitudeEditField_7Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.ParametersTab, 'numeric');
            app.AmplitudeEditField_2.Enable = 'off';
            app.AmplitudeEditField_8Label = uilabel(app.ParametersTab);
            app.AmplitudeEditField_8Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.ParametersTab, 'numeric');
            app.AmplitudeEditField_3.Enable = 'off';
            app.AmplitudeEditField_9Label = uilabel(app.ParametersTab);
            app.AmplitudeEditField_9Label.Text = 'Amplitude';
            app.AmplitudeEditField_4 = uieditfield(app.ParametersTab, 'numeric');
            app.AmplitudeEditField_4.Enable = 'off';
            app.AmplitudeEditField_10Label = uilabel(app.ParametersTab);
            app.AmplitudeEditField_10Label.Text = 'Amplitude';
            app.AmplitudeEditField_5 = uieditfield(app.ParametersTab, 'numeric');
            app.AmplitudeEditField_5.Enable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.ParametersTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.ParametersTab, 'numeric');
            app.FrequencyEditField.Enable = 'off';
            app.FrequencyEditField_2Label = uilabel(app.ParametersTab);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.ParametersTab, 'numeric');
            app.FrequencyEditField_2.Enable = 'off';
            app.FrequencyEditField_3Label = uilabel(app.ParametersTab);
            app.FrequencyEditField_3Label.Text = 'Frequency';
            app.FrequencyEditField_3 = uieditfield(app.ParametersTab, 'numeric');
            app.FrequencyEditField_3.Enable = 'off';
            app.FrequencyEditField_4Label = uilabel(app.ParametersTab);
            app.FrequencyEditField_4Label.Text = 'Frequency';
            app.FrequencyEditField_4 = uieditfield(app.ParametersTab, 'numeric');
            app.FrequencyEditField_4.Enable = 'off';
            app.FrequencyEditField_5Label = uilabel(app.ParametersTab);
            app.FrequencyEditField_5Label.Text = 'Frequency';
            app.FrequencyEditField_5 = uieditfield(app.ParametersTab, 'numeric');
            app.FrequencyEditField_5.Enable = 'off';
            app.PhaseEditFieldLabel = uilabel(app.ParametersTab);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.ParametersTab, 'numeric');
            app.PhaseEditField.Enable = 'off';
            app.PhaseEditField_2Label = uilabel(app.ParametersTab);
            app.PhaseEditField_2Label.Text = 'Phase';
            app.PhaseEditField_2 = uieditfield(app.ParametersTab, 'numeric');
            app.PhaseEditField_2.Enable = 'off';
            app.PhaseEditField_3Label = uilabel(app.ParametersTab);
            app.PhaseEditField_3Label.Text = 'Phase';
            app.PhaseEditField_3 = uieditfield(app.ParametersTab, 'numeric');
            app.PhaseEditField_3.Enable = 'off';
            app.PhaseEditField_4Label = uilabel(app.ParametersTab);
            app.PhaseEditField_4Label.Text = 'Phase';
            app.PhaseEditField_4 = uieditfield(app.ParametersTab, 'numeric');
            app.PhaseEditField_4.Enable = 'off';
            app.PhaseEditField_5Label = uilabel(app.ParametersTab);
            app.PhaseEditField_5Label.Text = 'Phase';
            app.PhaseEditField_5 = uieditfield(app.ParametersTab, 'numeric');
            app.PhaseEditField_5.Enable = 'off';
            app.Button_2 = uibutton(app.ParametersTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Icon = 'help.png';
            app.Button_2.Tooltip = {'Help'};
            app.Button_2.Text = '';
            app.CreatedSignalsTab = uitab(app.TabGroup);
            app.CreatedSignalsTab.Title = 'Created Signals';
            app.CreatedSignalsLabel = uilabel(app.CreatedSignalsTab);
            app.CreatedSignalsLabel.Text = 'Created Signals';
            app.UIAxes = uiaxes(app.CreatedSignalsTab);
            title(app.UIAxes, 'Time Series')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Amplitude (m)')
            app.UIAxes2 = uiaxes(app.CreatedSignalsTab);
            title(app.UIAxes2, 'Power Spectrum')
            xlabel(app.UIAxes2, 'Frequency (cpd)')
            ylabel(app.UIAxes2, 'Power per cycle per second (m^2 / cpd)')
            app.CANCELButton_2 = uibutton(app.CreatedSignalsTab, 'push');
            app.CANCELButton_2.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton_2.Text = 'CANCEL';
            app.NEXTButton = uibutton(app.CreatedSignalsTab, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.GOBACKButton = uibutton(app.CreatedSignalsTab, 'push');
            app.GOBACKButton.ButtonPushedFcn = createCallbackFcn(app, @GOBACKButtonPushed, true);
            app.GOBACKButton.Text = 'GO BACK';
            app.Button_3 = uibutton(app.CreatedSignalsTab, 'push');
            app.Button_3.Icon = 'help.png';
            app.Button_3.Tooltip = {'Help'};
            app.Button_3.Text = '';
            app.FilterParamsTab = uitab(app.TabGroup);
            app.FilterParamsTab.Title = 'Filter Params';
            app.FilterSignalParametersLabel = uilabel(app.FilterParamsTab);
            app.FilterSignalParametersLabel.Text = 'Filter Signal Parameters';
            app.TimescaleYearsEditFieldLabel = uilabel(app.FilterParamsTab);
            app.TimescaleYearsEditFieldLabel.Text = 'Timescale (Years)';
            app.TimescaleYearsEditField = uieditfield(app.FilterParamsTab, 'numeric');
            app.TimescaleYearsEditField.Tooltip = {'If no amount of years entered; the default value is 1 year. '};
            app.CANCELButton_3 = uibutton(app.FilterParamsTab, 'push');
            app.CANCELButton_3.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton_3.Text = 'CANCEL';
            app.FILTERButton = uibutton(app.FilterParamsTab, 'push');
            app.FILTERButton.ButtonPushedFcn = createCallbackFcn(app, @FILTERButtonPushed, true);
            app.FILTERButton.Text = 'FILTER';
            app.GOBACKButton_2 = uibutton(app.FilterParamsTab, 'push');
            app.GOBACKButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOBACKButton_2Pushed, true);
            app.GOBACKButton_2.Text = 'GO BACK';
            app.SelectaFilterLabel = uilabel(app.FilterParamsTab);
            app.SelectaFilterLabel.Text = 'Select a Filter';
            app.BoxcarFilterCheckBox = uicheckbox(app.FilterParamsTab);
            app.BoxcarFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxcarFilterCheckBoxValueChanged, true);
            app.BoxcarFilterCheckBox.Text = '     Boxcar Filter';
            app.SincFilterCheckBox = uicheckbox(app.FilterParamsTab);
            app.SincFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SincFilterCheckBoxValueChanged, true);
            app.SincFilterCheckBox.Text = '     Sinc Filter';
            app.SelectaSeriesLabel = uilabel(app.FilterParamsTab);
            app.SelectaSeriesLabel.Text = 'Select a Series';
            app.TimeSeriesCheckBox = uicheckbox(app.FilterParamsTab);
            app.TimeSeriesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeSeriesCheckBoxValueChanged, true);
            app.TimeSeriesCheckBox.Text = '     Time Series';
            app.PowerSpectrumCheckBox = uicheckbox(app.FilterParamsTab);
            app.PowerSpectrumCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerSpectrumCheckBoxValueChanged, true);
            app.PowerSpectrumCheckBox.Text = '     Power Spectrum';
            app.Button_4 = uibutton(app.FilterParamsTab, 'push');
            app.Button_4.Icon = 'help.png';
            app.Button_4.Tooltip = {'Help'};
            app.Button_4.Text = '';
            app.Label = uilabel(app.FilterParamsTab);
            app.Label.Text = 'Note: Timescale must be in the range of the amount years entered in the frequency';
            app.FilteredSignalsTab = uitab(app.TabGroup);
            app.FilteredSignalsTab.Title = 'Filtered Signals';
            app.FilteredSignalsLabel = uilabel(app.FilteredSignalsTab);
            app.FilteredSignalsLabel.Text = 'Filtered Signals';
            app.UIAxes_2 = uiaxes(app.FilteredSignalsTab);
            title(app.UIAxes_2, 'Time Series')
            xlabel(app.UIAxes_2, 'Time (days)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.UIAxes2_2 = uiaxes(app.FilteredSignalsTab);
            title(app.UIAxes2_2, 'Power Spectrum')
            xlabel(app.UIAxes2_2, 'Cycles per Day')
            ylabel(app.UIAxes2_2, 'Frequency')
            app.CANCELButton_4 = uibutton(app.FilteredSignalsTab, 'push');
            app.CANCELButton_4.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton_4.Text = 'CANCEL';
            app.NEXTButton_2 = uibutton(app.FilteredSignalsTab, 'push');
            app.NEXTButton_2.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_2Pushed, true);
            app.NEXTButton_2.Text = 'NEXT';
            app.GOBACKButton_3 = uibutton(app.FilteredSignalsTab, 'push');
            app.GOBACKButton_3.ButtonPushedFcn = createCallbackFcn(app, @GOBACKButton_3Pushed, true);
            app.GOBACKButton_3.Text = 'GO BACK';
            app.Button_5 = uibutton(app.FilteredSignalsTab, 'push');
            app.Button_5.Icon = 'help.png';
            app.Button_5.Tooltip = {'Help'};
            app.Button_5.Text = '';
            app.SignalAnalysisTab = uitab(app.TabGroup);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.AnalyzingtheFilteredSignalsLabel = uilabel(app.SignalAnalysisTab);
            app.AnalyzingtheFilteredSignalsLabel.Text = 'Analyzing the Filtered Signals';
            app.HTML4 = uihtml(app.SignalAnalysisTab);
            app.HTML4.HTMLSource = '<p style="font-family: Helvetica; font-size: 15px"> During the process of the application, you could observe the generation of the time series and the power spectrum; based on the data entered in the beginning of the process. This signals are generated by two functions: <br/>&emsp;&emsp;<span style="font-weight: 600"> amplitude * sin(frequency + phase) </span> for the time series <br/>&emsp;&emsp; <span style="font-weight: 600">amplitude * sin ((1/frequency) + phase)</span> for the power spectrum<br/><br/> After deciding how many years to filter the signals and which series and filter, you wanted to use; the signals underwent a process of reducing frequency. Since to estimate the value of SLR the only viable information is in the low frequency, we need to apply low passband filters, such as the Sinc Filter and the Boxcar Filter.<br/><br/> After filtering the data, in the filtered graphs if applied to the time series: the blue signal is the low frequency signal, the red lines are the limits ofwhere the data in the signal is valid to calculate the SLR trend. Finally, the yellow line is the linear regression that provides the value of the SLR trend. If applying the filters in the power spectrum, the green line is the cutoff of the signals, the valid data is on the left of the line; and the red signal is the low frequency signal.</p>';
            app.FINISHButton = uibutton(app.SignalAnalysisTab, 'push');
            app.FINISHButton.ButtonPushedFcn = createCallbackFcn(app, @FINISHButtonPushed, true);
            app.FINISHButton.Text = 'FINISH';
            app.GOBACKButton_4 = uibutton(app.SignalAnalysisTab, 'push');
            app.GOBACKButton_4.ButtonPushedFcn = createCallbackFcn(app, @GOBACKButton_4Pushed, true);
            app.GOBACKButton_4.Text = 'GO BACK';
            % Show the figure after all components are created
            app.SLRSignalAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tesinaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLRSignalAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLRSignalAnalysisUIFigure)
        end
    end
end